classdef Yes_No < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure            % Yes&No
        Label          matlab.ui.control.Label     % Yes & No recognition
        Button         matlab.ui.control.Button    % Start
        Button2        matlab.ui.control.Button    % Stop
        LabelEditField matlab.ui.control.Label     % Resualt
        Resualt        matlab.ui.control.EditField
    end


    properties (Access = public)
        Property % Description
        yes_model
        no_model
        recorder = audiorecorder(16000,16,1)
        Adata
        mfcc
        yes
        no
        Fs=16000
    end

    methods (Access = private)
        
        function [] = resualt(app,n,y)
            if n > y
                app.Resualt.Value = 'خیر' ;
            else
                app.Resualt.Value = 'بله' ;
            end
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add paths for RastaMat
            addpath('Libraries/rastamat');           
        end

        % Button button pushed function
        function StartRecord(app)
            app.recorder.record();
            app.Resualt.Value = 'a' ;
        end

        % Button2 button pushed function
        function StopRecord(app)
            app.recorder.stop();
            app.Adata = app.recorder.getaudiodata();
            [app.no , app.yes] = CheckT(app.Adata);
             if app.no > app.yes
                app.Resualt.Value = 'خیر' ;
            else
                app.Resualt.Value = 'بله' ;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Yes&No';
            setAutoResize(app, app.UIFigure, true)
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Yes & No recognition';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @StartRecord);
            app.Button.Text = 'Start';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @StopRecord);
            app.Button2.Text = 'Stop';
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'Resualt';
            app.Resualt = uieditfield(app.UIFigure, 'text');
            app.Resualt.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Yes_No()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end